classdef SU2app2NEMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SU2ResidualsViewerUIFigure      matlab.ui.Figure
        RefreshtimeEditField            matlab.ui.control.NumericEditField
        RefreshtimeEditFieldLabel       matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        FileDirectoryEditField          matlab.ui.control.EditField
        FileDirectoryEditFieldLabel     matlab.ui.control.Label
        Switchplot                      matlab.ui.control.Switch
        SwitchplotLabel                 matlab.ui.control.Label
        SelectedFilenameEditField       matlab.ui.control.EditField
        SelectedFilenameEditFieldLabel  matlab.ui.control.Label
        SelectSU2HistorycsvfileButton   matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        ResidualsTab                    matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ForcesTab                       matlab.ui.container.Tab
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        LatestResidualTab               matlab.ui.container.Tab
        UIAxes5_6                       matlab.ui.control.UIAxes
        UIAxes5_5                       matlab.ui.control.UIAxes
        UIAxes5_4                       matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes5_3                       matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        SU2AnalysisTab                  matlab.ui.container.Tab
        STOPButton                      matlab.ui.control.Button
        SU2CFDTextArea                  matlab.ui.control.TextArea
        SU2CFDTextAreaLabel             matlab.ui.control.Label
        SU2_CFDButton                   matlab.ui.control.Button
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        SU2DEFORMButton                 matlab.ui.control.Button
        SelectedFileEditField           matlab.ui.control.EditField
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        SelectCFGfileButton             matlab.ui.control.Button
        GridconvertTab                  matlab.ui.container.Tab
        UITable3_2                      matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        ConvertOpenfoamButton           matlab.ui.control.Button
        FiledirectoryEditField          matlab.ui.control.EditField
        FiledirectoryEditFieldLabel     matlab.ui.control.Label
        FileselectedEditField           matlab.ui.control.EditField
        FileselectedEditFieldLabel      matlab.ui.control.Label
        ChooseMSHFileButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        p % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectSU2HistorycsvfileButton
        function SelectSU2HistorycsvfileButtonPushed(app, event)
            [filename, path] = uigetfile({'*.csv','comma seperated value (*.csv)'}, 'Select configuration file');
    if isequal(filename,0)
        disp('User selected Cancel');
    else
        disp(['User selected ', fullfile(path,filename)])
    end
    app.SelectedFilenameEditField.Value=filename;
     app.FileDirectoryEditField.Value=path;
%file = readmatrix(filename);  % replace with your filename


        end

        % Value changed function: Switchplot
        function SwitchplotValueChanged(app, event)
           
        end

        % Button pushed function: SelectCFGfileButton
        function SelectCFGfileButtonPushed(app, event)
              [filename, path] = uigetfile({'*.cfg','Config file (*.cfg)'}, 'Select Configuration file');
    if isequal(filename,0)
        disp('User selected Cancel');
    else
        disp(['User selected ', fullfile(path,filename)])

    end
     app.SelectedFileEditField.Value= filename;
     app.FilePathEditField.Value=path;
        end

        % Button pushed function: SU2DEFORMButton
        function SU2DEFORMButtonPushed(app, event)
            filename=app.SelectedFileEditField.Value;
            path=app.FilePathEditField.Value
            [status, output] = system(cd(path));
            
            currentDataS =  app.UITable2.Data
    newDataS = [currentDataS; status];
     % Update the table data
   app.UITable2.Data = newDataS;

             currentDataA =  app.UITable.Data
    newDataA = [currentDataA; strsplit(output, '\n')'];
     % Update the table data
    app.UITable.Data = newDataA;

             comm1 = (['SU2_DEF ' './' filename ])
             [status, output] = system([comm1]);

             currentDataS =  app.UITable2.Data;
    newDataS = [currentDataS; status];
     % Update the table data
   app.UITable2.Data = newDataS;

             currentDataA =  app.UITable.Data;
    newDataA = [currentDataA; strsplit(output, '\n')'];
     % Update the table data
    app.UITable.Data = newDataA;

        end

        % Button pushed function: SU2_CFDButton
        function SU2_CFDButtonPushed(app, event)
      path=app.FilePathEditField.Value
      filename=app.SelectedFileEditField.Value;
            [status, output] = system(cd(path))
            command=(['SU2_CFD ' './' filename ])

    % Open PowerShell process
[status,outputc]= system([command]);
%app.p=outputc
    % Read and display output in real time
  
            %output = fread(outputc)';
           % app.SU2CFDTextArea.Value = [app.SU2CFDTextArea.Value; char(output)];
           currentDataA =  app.UITable.Data
    newDataA = [currentDataA; strsplit(outputc, '\n')'];
     % Update the table data
    app.UITable.Data = newDataA;
   

    % Close process


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            close(app.p);
        end

        % Button pushed function: ChooseMSHFileButton
        function ChooseMSHFileButtonPushed(app, event)
             [Mfilename, Mpath] = uigetfile({'*.cfg','Config file (*.cfg)'}, 'Select Configuration file');
    if isequal(Mfilename,0)
        disp('User selected Cancel');
    else
        disp(['User selected ', fullfile(Mpath,Mfilename)])

    end
     app.FileselectedEditField.Value= Mfilename;
     app.FiledirectoryEditField.Value=Mpath
        end

        % Button pushed function: ConvertOpenfoamButton
        function ConvertOpenfoamButtonPushed(app, event)
        Mfilename=app.FileselectedEditField.Value
        Mpath= app.FiledirectoryEditField.Value

        % change to file directory
        path=(['cd ' Mpath])
        %system(['wsl ' command]);
         [status, Moutput] = system(['wsl' path]);
            
            currentDataS =  app.UITable3_2.Data
             newDataS = [currentDataS; status];
     % Update the table data
             app.UITable3_2.Data = newDataS;

             currentDataA =  app.UITable3.Data
             newDataA = [currentDataA; strsplit(output, '\n')'];
     % Update the table data
             app.UITable3.Data = newDataA;

             % copy to the linux directory
             LinuxPath =(['cp ' filename ' ~/MESHBL'])
             [status, Moutput] = system(LinuxPath);


        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
             value = app.Switchplot.Value;
             filename=app.SelectedFilenameEditField.Value;
             t=0+app.RefreshtimeEditField.Value
             path=app.FileDirectoryEditField.Value
            [status, output] = system(cd(path));
            %switch value
                %case'On'
                nrows = 350;
          while true  
                    % Start a while loop for continuously reading dat
                    while true
S=app.Switchplot.Value
                        if S== 'On'
                        file = readmatrix(filename);
                         start_row = max(size(file, 1) - nrows + 1, 1);
                         latest_data = file(start_row:end, :);

    % Read data from Excel file
    iter = file(:,3);
    Rho = file(:,4);
    RhoU = file(:,5);
    RhoV = file(:,6);
    RhoE = file(:,7);
    Rhonu = file(:,8);
    Cd = file(:,11);
    Cl = file(:,12);
    % Plot data
   % refreshdata(iter);

    iterl = latest_data(:,3);
    Rhol = latest_data(:,4);
    RhoUl = latest_data(:,5);
    RhoVl = latest_data(:,6);
    RhoEl = latest_data(:,7);
    Rhonul = latest_data(:,8);
    Cdl = latest_data(:,11);
    Cll = latest_data(:,12);
   
    plot(app.UIAxes,iter, Rho,"r");
    plot(app.UIAxes5,iterl, Rhol,"r");
    %hold on
    %xlabel(Rho_axes, 'Iteration');
%ylabel(Rho_axes, 'Rho');
%title(Rho_axes, 'Rho vs Iteration');
    % refreshdata(Rho);
    %hold on
    plot(app.UIAxes_2,iter, RhoU,'b');
    plot(app.UIAxes5_2,iterl, RhoUl,'b');
    %xlabel(RhoU_axes, 'Iteration');
%ylabel(RhoU_axes, 'RhoU');
%title(RhoU_axes, 'RhoU vs Iteration');
    % refreshdata(RhoU);
   %hold on
    plot(app.UIAxes_3,iter, RhoV,'g');
 plot(app.UIAxes5_3,iterl, RhoVl,'g');
%xlabel(RhoV_axes5, 'Iteration');
%ylabel(RhoV_axes, 'RhoV');
%title(RhoV_axes, 'RhoV vs Iteration');
    % refreshdata(RhoV);
    %hold on
    plot(app.UIAxes2,iter, RhoE,'k');
    plot(app.UIAxes5_4,iterl, RhoEl,'k');

%xlabel(RhoE_axes, 'Iteration');
%ylabel(RhoE_axes, 'RhoE');
%title(RhoE_axes, 'RhoE vs Iteration');
   %  refreshdata(RhoE);
    %hold on
    plot(app.UIAxes2_2,iter, Rhonu,'c');
      plot(app.UIAxes5_5,iterl, Rhonul,'c');
 %hold on
%xlabel(Rhonu_axes, 'Iteration');
%ylabel(Rhonu_axes, 'Rho NU');
%title(Rhonu_axes, 'Rho NU vs Iteration');
   % refreshdata(Rhonu);
    %hold on

    plot(app.UIAxes2_3,iter,Rho,'r',iter, RhoU,'b',iter, RhoV,'g',iter, RhoE,'k',iter, Rhonu,'c');
    legend(app.UIAxes2_3,'Rho','RhoU','RhoV','RhoE','RhoNu')

     plot(app.UIAxes5_6,iterl,Rhol,'r',iterl, RhoUl,'b',iterl, RhoVl,'g',iterl, RhoEl,'k',iterl, Rhonul,'c');
    legend(app.UIAxes5_6,'Rho','RhoU','RhoV','RhoE','RhoNu')

    plot(app.UIAxes4,iter, Cd,'c');
    plot(app.UIAxes7,iterl, Cdl,'c');

    plot(app.UIAxes4_2,iter, Cl,'c');
    plot(app.UIAxes7_2,iterl, Cll,'c');
    
     pause(t);              
     % Refresh the figure to show the updated data
     drawnow

                        elseif S=='Off'
         break
     end
                    %end
                     %case'Off'
         %return
                    end

          end
     

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SU2ResidualsViewerUIFigure = uifigure('Visible', 'off');
            app.SU2ResidualsViewerUIFigure.Name = 'SU2 Residuals Viewer';
            app.TabGroup = uitabgroup(app.SU2ResidualsViewerUIFigure);
            app.ResidualsTab = uitab(app.TabGroup);
            app.ResidualsTab.Title = 'Residuals';
            app.UIAxes = uiaxes(app.ResidualsTab);
            title(app.UIAxes, 'Rho vs Iteration')
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Rho')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.ResidualsTab);
            title(app.UIAxes2, 'Rho E vs Iteration')
            xlabel(app.UIAxes2, 'Itreration')
            ylabel(app.UIAxes2, 'Rho E')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.ResidualsTab);
            title(app.UIAxes_2, 'Rho U vs Iteration')
            xlabel(app.UIAxes_2, 'Iteration')
            ylabel(app.UIAxes_2, 'Rho U')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.ResidualsTab);
            title(app.UIAxes2_2, 'Rho Nu vs Iteration')
            xlabel(app.UIAxes2_2, 'Iteration')
            ylabel(app.UIAxes2_2, 'Rho Nu')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ResidualsTab);
            title(app.UIAxes_3, 'Rho V vs Iteration')
            xlabel(app.UIAxes_3, 'Iteration')
            ylabel(app.UIAxes_3, 'Rho V')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2_3 = uiaxes(app.ResidualsTab);
            title(app.UIAxes2_3, 'Residuals')
            xlabel(app.UIAxes2_3, 'Iteration')
            ylabel(app.UIAxes2_3, 'Residuals')
            zlabel(app.UIAxes2_3, 'Z')
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.UIAxes4 = uiaxes(app.ForcesTab);
            title(app.UIAxes4, 'Co-efficient of Drag vs Iteration')
            xlabel(app.UIAxes4, 'Co-efficient of Drag ')
            ylabel(app.UIAxes4, 'Iteration')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.ForcesTab);
            title(app.UIAxes4_2, 'Co-efficient of Lift vs Iteration')
            xlabel(app.UIAxes4_2, 'Iteration')
            ylabel(app.UIAxes4_2, 'Co-efficient of Lift ')
            zlabel(app.UIAxes4_2, 'Z')
            app.LatestResidualTab = uitab(app.TabGroup);
            app.LatestResidualTab.Title = 'Latest Residual';
            app.UIAxes5 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes5, 'Rho vs Iteration')
            xlabel(app.UIAxes5, 'Iteration')
            ylabel(app.UIAxes5, 'Rho')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes5_2, 'RhoU vs Iteration')
            xlabel(app.UIAxes5_2, 'Iteration')
            ylabel(app.UIAxes5_2, 'Rho U')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes5_3 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes5_3, 'RhoV vs Iteration')
            xlabel(app.UIAxes5_3, 'Iteration')
            ylabel(app.UIAxes5_3, 'Rho V')
            zlabel(app.UIAxes5_3, 'Z')
            app.UIAxes7 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes7, 'Cd vs Iteration')
            xlabel(app.UIAxes7, 'Iteraiton')
            ylabel(app.UIAxes7, 'Cd')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7_2 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes7_2, 'Cl vs Iteration')
            xlabel(app.UIAxes7_2, 'Iteration')
            ylabel(app.UIAxes7_2, 'Cl')
            zlabel(app.UIAxes7_2, 'Z')
            app.UIAxes5_4 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes5_4, 'RhoE vs Iteration')
            xlabel(app.UIAxes5_4, 'Iteration')
            ylabel(app.UIAxes5_4, 'Rho E')
            zlabel(app.UIAxes5_4, 'Z')
            app.UIAxes5_5 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes5_5, 'RhoNU vs Iteration')
            xlabel(app.UIAxes5_5, 'Iteration')
            ylabel(app.UIAxes5_5, 'Rho NU')
            zlabel(app.UIAxes5_5, 'Z')
            app.UIAxes5_6 = uiaxes(app.LatestResidualTab);
            title(app.UIAxes5_6, 'Residuals vs Iteration')
            xlabel(app.UIAxes5_6, 'Iteration')
            ylabel(app.UIAxes5_6, 'Residuals')
            zlabel(app.UIAxes5_6, 'Z')
            app.SU2AnalysisTab = uitab(app.TabGroup);
            app.SU2AnalysisTab.Title = 'SU2 Analysis';
            app.SelectCFGfileButton = uibutton(app.SU2AnalysisTab, 'push');
            app.SelectCFGfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCFGfileButtonPushed, true);
            app.SelectCFGfileButton.Text = 'Select CFG file';
            app.SelectedFileEditFieldLabel = uilabel(app.SU2AnalysisTab);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.SU2AnalysisTab, 'text');
            app.SU2DEFORMButton = uibutton(app.SU2AnalysisTab, 'push');
            app.SU2DEFORMButton.ButtonPushedFcn = createCallbackFcn(app, @SU2DEFORMButtonPushed, true);
            app.SU2DEFORMButton.Text = 'SU2 DEFORM';
            app.UITable = uitable(app.SU2AnalysisTab);
            app.UITable.ColumnName = {'OUTPUT'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.SU2AnalysisTab);
            app.UITable2.ColumnName = {'Status'};
            app.UITable2.RowName = {};
            app.FilePathEditFieldLabel = uilabel(app.SU2AnalysisTab);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.SU2AnalysisTab, 'text');
            app.SU2_CFDButton = uibutton(app.SU2AnalysisTab, 'push');
            app.SU2_CFDButton.ButtonPushedFcn = createCallbackFcn(app, @SU2_CFDButtonPushed, true);
            app.SU2_CFDButton.Text = 'SU2_CFD';
            app.SU2CFDTextAreaLabel = uilabel(app.SU2AnalysisTab);
            app.SU2CFDTextAreaLabel.Text = 'SU2CFD';
            app.SU2CFDTextArea = uitextarea(app.SU2AnalysisTab);
            app.STOPButton = uibutton(app.SU2AnalysisTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.GridconvertTab = uitab(app.TabGroup);
            app.GridconvertTab.Title = 'Grid convert';
            app.ChooseMSHFileButton = uibutton(app.GridconvertTab, 'push');
            app.ChooseMSHFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseMSHFileButtonPushed, true);
            app.ChooseMSHFileButton.Text = 'Choose .MSH File';
            app.FileselectedEditFieldLabel = uilabel(app.GridconvertTab);
            app.FileselectedEditFieldLabel.Text = 'File  selected';
            app.FileselectedEditField = uieditfield(app.GridconvertTab, 'text');
            app.FiledirectoryEditFieldLabel = uilabel(app.GridconvertTab);
            app.FiledirectoryEditFieldLabel.Text = 'File directory';
            app.FiledirectoryEditField = uieditfield(app.GridconvertTab, 'text');
            app.ConvertOpenfoamButton = uibutton(app.GridconvertTab, 'push');
            app.ConvertOpenfoamButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertOpenfoamButtonPushed, true);
            app.ConvertOpenfoamButton.Text = 'Convert Openfoam';
            app.UITable3 = uitable(app.GridconvertTab);
            app.UITable3.ColumnName = {'Output'};
            app.UITable3.RowName = {};
            app.UITable3_2 = uitable(app.GridconvertTab);
            app.UITable3_2.ColumnName = {'Status'};
            app.UITable3_2.RowName = {};
            app.Image = uiimage(app.SU2ResidualsViewerUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Untitled.gif');
            app.SelectSU2HistorycsvfileButton = uibutton(app.SU2ResidualsViewerUIFigure, 'push');
            app.SelectSU2HistorycsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSU2HistorycsvfileButtonPushed, true);
            app.SelectSU2HistorycsvfileButton.Text = 'Select SU2 History.csv file';
            app.SelectedFilenameEditFieldLabel = uilabel(app.SU2ResidualsViewerUIFigure);
            app.SelectedFilenameEditFieldLabel.Text = 'Selected File name';
            app.SelectedFilenameEditField = uieditfield(app.SU2ResidualsViewerUIFigure, 'text');
            app.SwitchplotLabel = uilabel(app.SU2ResidualsViewerUIFigure);
            app.SwitchplotLabel.Text = 'Switch plot';
            app.Switchplot = uiswitch(app.SU2ResidualsViewerUIFigure, 'slider');
            app.Switchplot.ValueChangedFcn = createCallbackFcn(app, @SwitchplotValueChanged, true);
            app.FileDirectoryEditFieldLabel = uilabel(app.SU2ResidualsViewerUIFigure);
            app.FileDirectoryEditFieldLabel.Text = 'File Directory';
            app.FileDirectoryEditField = uieditfield(app.SU2ResidualsViewerUIFigure, 'text');
            app.RUNButton = uibutton(app.SU2ResidualsViewerUIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.RefreshtimeEditFieldLabel = uilabel(app.SU2ResidualsViewerUIFigure);
            app.RefreshtimeEditFieldLabel.Text = 'Refresh time';
            app.RefreshtimeEditField = uieditfield(app.SU2ResidualsViewerUIFigure, 'numeric');
            % Show the figure after all components are created
            app.SU2ResidualsViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SU2app2NEMO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SU2ResidualsViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SU2ResidualsViewerUIFigure)
        end
    end
end